classdef ImageResotration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        RESTOREDIMAGELabel     matlab.ui.control.Label
        Image_3                matlab.ui.control.Image
        DEGRADEDIMAGELabel     matlab.ui.control.Label
        ORIGINALIMAGELabel     matlab.ui.control.Label
        Image_2                matlab.ui.control.Image
        Image                  matlab.ui.control.Image
        IMAGERESTORATIONLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            img = imread('hawk.png');
            app.Image.ImageSource = img;
            img2 = imnoise(img, 'salt & pepper', 0.2);
            app.Image_2.ImageSource = img2;
            r = medfilt2(img2(:, :, 1), [3 3]);
            g = medfilt2(img2(:, :, 2), [3 3]);
            b = medfilt2(img2(:, :, 3), [3 3]);
            img3 = cat(3, r, g, b);
            app.Image_3.ImageSource = img3;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.IMAGERESTORATIONLabel = uilabel(app.UIFigure);
            app.IMAGERESTORATIONLabel.Text = 'IMAGE RESTORATION';
            app.Image = uiimage(app.UIFigure);
            app.Image_2 = uiimage(app.UIFigure);
            app.ORIGINALIMAGELabel = uilabel(app.UIFigure);
            app.ORIGINALIMAGELabel.Text = 'ORIGINAL IMAGE';
            app.DEGRADEDIMAGELabel = uilabel(app.UIFigure);
            app.DEGRADEDIMAGELabel.Text = 'DEGRADED IMAGE';
            app.Image_3 = uiimage(app.UIFigure);
            app.RESTOREDIMAGELabel = uilabel(app.UIFigure);
            app.RESTOREDIMAGELabel.Text = 'RESTORED IMAGE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageResotration

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end