classdef Interpolation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        INTERPOLATIONResizeLabel  matlab.ui.control.Label
        img_4                     matlab.ui.control.UIAxes
        img_3                     matlab.ui.control.UIAxes
        img_2                     matlab.ui.control.UIAxes
        img_1                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            img = imread('clock.png');
            NN_interpolation = imresize(img, 0.5, 'nearest');
            BL_interpolation = imresize(img, 0.5, 'bilinear');
            BC_interpolation = imresize(img, 0.5, 'bicubic');
            
            imshow(img, 'Parent', app.img_1);
            imshow(NN_interpolation, 'Parent', app.img_2);
            imshow(BL_interpolation, 'Parent', app.img_3);
            imshow(BC_interpolation, 'Parent', app.img_4);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.img_1 = uiaxes(app.UIFigure);
            title(app.img_1, 'Original Image')
            app.img_2 = uiaxes(app.UIFigure);
            title(app.img_2, 'Nearest Neighbor Interpolation Image')
            app.img_3 = uiaxes(app.UIFigure);
            title(app.img_3, 'Bilinear Interpolation Image')
            app.img_4 = uiaxes(app.UIFigure);
            title(app.img_4, 'Bicubic Interpolation Image')
            app.INTERPOLATIONResizeLabel = uilabel(app.UIFigure);
            app.INTERPOLATIONResizeLabel.Text = 'INTERPOLATION (Resize)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interpolation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end